classdef GaussianChannelDigitalModelApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        PressStarttorunCcodeLabel  matlab.ui.control.Label
        StartButton                matlab.ui.control.Button
        RightPanel                 matlab.ui.container.Panel
        UIAxes                     matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function startAnalysys(app, event)
            cla(app.UIAxes);
            system('g++ -std=c++11 GaussianChannelDigitalModel.cpp -o GaussianChannelDigitalModel');
            system('./GaussianChannelDigitalModel');
            inputData = csvread('BERdata.csv');
            digitsOld = digits(10);
            t = 1;
            j = 1;
            m = 1;
            for i = 1:length(inputData)
                k = vpa(inputData(i));
                if k ~= -1
                    if t == 1
                        SNR(j) = k;
                        j = j + 1;
                    elseif t == 2
                        modulationOrders(j) = k;
                        j = j + 1;
                    elseif t == 3
                        BER(j,m) = k;
                        j = j + 1;
                        if j > length(SNR) && m ~= length(modulationOrders)
                            m = m + 1;
                            j = 1;
                        end
                    end
                else
                    t = t + 1;
                    j = 1;
                end
            end
            hold(app.UIAxes, 'on');
            for i = 1:length(modulationOrders)
                plot(app.UIAxes,SNR,BER(:,i),'DisplayName',['practical QAM-' num2str(double(modulationOrders(i)))]);
            end
            grid(app.UIAxes, 'on');
            legend(app.UIAxes);

        end

        % Callback function
        function plotData(app, event)

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {145, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.StartButton = uibutton(app.LeftPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @startAnalysys, true);
            app.StartButton.Text = 'Start';
            app.PressStarttorunCcodeLabel = uilabel(app.LeftPanel);
            app.PressStarttorunCcodeLabel.Visible = 'off';
            app.PressStarttorunCcodeLabel.Text = {'Press "Start" '; 'to run C++ code'};
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            xlabel(app.UIAxes, 'SNR')
            ylabel(app.UIAxes, 'BER')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.YScale = 'log';
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @updateAppLayout, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GaussianChannelDigitalModelApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end